classdef REPRODUCIR_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                     matlab.ui.Figure
        ADELANTARButton             matlab.ui.control.Button
        TiempoActualLabel           matlab.ui.control.Label
        DuracionLabel               matlab.ui.control.Label
        NombreMusicaLabel           matlab.ui.control.Label
        SECCIONARMODODropDown       matlab.ui.control.DropDown
        SECCIONARMODODropDownLabel  matlab.ui.control.Label
        CARGARMUSICAButton          matlab.ui.control.Button
        text31                      matlab.ui.control.Label
        text30                      matlab.ui.control.Label
        text29                      matlab.ui.control.Label
        Gain15                      matlab.ui.control.Slider
        Gain14                      matlab.ui.control.Slider
        Gain13                      matlab.ui.control.Slider
        text28                      matlab.ui.control.Label
        text27                      matlab.ui.control.Label
        text26                      matlab.ui.control.Label
        text25                      matlab.ui.control.Label
        text24                      matlab.ui.control.Label
        text23                      matlab.ui.control.Label
        text22                      matlab.ui.control.Label
        text21                      matlab.ui.control.Label
        text20                      matlab.ui.control.Label
        text19                      matlab.ui.control.Label
        text18                      matlab.ui.control.Label
        text17                      matlab.ui.control.Label
        Gain11                      matlab.ui.control.Slider
        Gain10                      matlab.ui.control.Slider
        Gain12                      matlab.ui.control.Slider
        Gain9                       matlab.ui.control.Slider
        Gain8                       matlab.ui.control.Slider
        Gain7                       matlab.ui.control.Slider
        text10                      matlab.ui.control.Label
        text2                       matlab.ui.control.Label
        Gain6                       matlab.ui.control.Slider
        Gain5                       matlab.ui.control.Slider
        VOLUMEN                     matlab.ui.control.Slider
        Gain4                       matlab.ui.control.Slider
        Gain3                       matlab.ui.control.Slider
        Gain2                       matlab.ui.control.Slider
        Gain1                       matlab.ui.control.Slider
        PARA                        matlab.ui.control.Button
        CONTINUAR                   matlab.ui.control.Button
        PAUSA                       matlab.ui.control.Button
        PLAY                        matlab.ui.control.Button
        Image                       matlab.ui.control.Image
    end

    
    properties (Access = private)
        ArchivoActual = ''% Description
        DuracionMusica = 0
        SampleRateMusica = 0
        TiempoActual = 0
        TiempoAcumulado = 0
        TimerReproduccion
        TiempoInicio
    end
    
    methods (Access = private)
        
        % Code that executes after component creation
        function REPRODUCIR_OpeningFcn(app, varargin)
            % --- Executes just before REPRODUCIR is made visible.

            % Centrar la aplicación en la pantalla
           
            movegui(app.figure1, 'onscreen');

            % Create GUIDE-style callback args
            [hObject, eventdata, handles] = ...
                convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % Volumen inicial
            set(handles.VOLUMEN,'Value',1);


            % Inicializar Gains
            set_param('Ecualizador/Gain1','Gain','1');
            set_param('Ecualizador/Gain2','Gain','1');
            set_param('Ecualizador/Gain3','Gain','1');
            set_param('Ecualizador/Gain4','Gain','1');
            set_param('Ecualizador/Gain5','Gain','1');
            set_param('Ecualizador/Gain6','Gain','1');
            set_param('Ecualizador/Gain7','Gain','1');
            set_param('Ecualizador/Gain8','Gain','1');
            set_param('Ecualizador/Gain9','Gain','1');
            set_param('Ecualizador/Gain10','Gain','1');
            set_param('Ecualizador/Gain11','Gain','1');
            set_param('Ecualizador/Gain12','Gain','1');
            set_param('Ecualizador/Gain13','Gain','1');
            set_param('Ecualizador/Gain14','Gain','1');
            set_param('Ecualizador/Gain15','Gain','1');

            % Inicializar sliders
            for k = 1:15
                nombreSlider = sprintf('Gain%d', k);
                app.(nombreSlider).Value = 1;
            end

            app.VOLUMEN.Value = 1;

            handles.output = hObject;
            guidata(hObject, handles);
        end


        % Función privada para actualizar los sliders y los bloques de Simulink
        function actualizarGains(app, ganancias)

            for k = 1:15
                nombreSlider = sprintf('Gain%d', k);
                nombreBloque = sprintf('Ecualizador/Gain%d', k);

                % Actualizar slider en App Designer
                app.(nombreSlider).Value = ganancias(k);

                % Actualizar ganancia en Simulink
                set_param(nombreBloque, ...
                    'Gain', ...
                    num2str(ganancias(k)));
            end

        end

        % -------------------------------------------
        function actualizarDuracion(app)

            if isempty(app.ArchivoActual)
                return;
            end

            infoAudio = audioinfo(app.ArchivoActual);

            app.DuracionMusica = infoAudio.Duration;
            app.SampleRateMusica = infoAudio.SampleRate;

            minutos = floor(app.DuracionMusica / 60);
            segundos = floor(mod(app.DuracionMusica, 60));

            app.DuracionLabel.Text = sprintf('Duración: %02d:%02d', minutos, segundos);

        end
        % --------------------------------------------
        function actualizarTiempo(app)

            if isempty(app.ArchivoActual)
                return;
            end

            % Tiempo desde el último PLAY o CONTINUAR
            tiempoTranscurrido = toc(app.TiempoInicio);

            % Tiempo total = lo que ya teníamos + lo transcurrido
            app.TiempoActual = app.TiempoAcumulado + tiempoTranscurrido;

            % No superar la duración de la canción
            if app.TiempoActual >= app.DuracionMusica
                app.TiempoActual = app.DuracionMusica;

                % Detener timer al terminar
                if ~isempty(app.TimerReproduccion) && ...
                        isvalid(app.TimerReproduccion)

                    stop(app.TimerReproduccion);
                end
            end

            % Convertir a minutos y segundos
            minutos = floor(app.TiempoActual / 60);
            segundos = floor(mod(app.TiempoActual, 60));

            % Mostrar
            app.TiempoActualLabel.Text = sprintf('%02d:%02d', ...
                minutos, segundos);

            drawnow limitrate;
        end
      
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn2(app)
            % Abrir automáticamente el modelo de Simulink
            rutaModelo = 'C:\Users\huall\Downloads\E}cualizaodorLaptopAntigua\ECUALIZADOR\Ecualizador.slx';

            if ~bdIsLoaded('Ecualizador')
                open_system(rutaModelo);
            end
        end

        % Button pushed function: CONTINUAR
        function CONTINUAR_Callback(app, event)
            % Verificar que haya una canción cargada
            if isempty(app.ArchivoActual)
                return;
            end

            % Crear un nuevo punto de inicio
            % El tiempo anterior ya está guardado en TiempoAcumulado
            app.TiempoInicio = tic;

            % Continuar Simulink
            set_param(gcs,'SimulationCommand','Continue');

            % Crear timer si no existe
            if isempty(app.TimerReproduccion) || ...
                    ~isvalid(app.TimerReproduccion)

                app.TimerReproduccion = timer( ...
                    'ExecutionMode', 'fixedRate', ...
                    'Period', 0.2, ...
                    'BusyMode', 'drop', ...
                    'TimerFcn', @(src,event) app.actualizarTiempo());

            end

            % Iniciar timer
            if strcmp(app.TimerReproduccion.Running, 'off')
                start(app.TimerReproduccion);
            end
        end

        % Value changed function: Gain10
        function Gain10_Callback(app, event)
            % --- Executes on slider movement.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            Ga10=get(hObject,'Value');
            set_param('Ecualizador/Gain10','Gain',num2str(Ga10));
        end

        % Value changed function: Gain11
        function Gain11_Callback(app, event)
            % --- Executes on slider movement.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            Ga11=get(hObject,'Value');
            set_param('Ecualizador/Gain11','Gain',num2str(Ga11));
        end

        % Value changed function: Gain12
        function Gain12_Callback(app, event)
            % --- Executes on slider movement.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            Ga12=get(hObject,'Value');
            set_param('Ecualizador/Gain12','Gain',num2str(Ga12));
        end

        % Value changed function: Gain13
        function Gain13_Callback(app, event)
            % --- Executes on slider movement.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            Ga13=get(hObject,'Value');
            set_param('Ecualizador/Gain13','Gain',num2str(Ga13));
        end

        % Value changed function: Gain14
        function Gain14_Callback(app, event)
            % --- Executes on slider movement.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            Ga14=get(hObject,'Value');
            set_param('Ecualizador/Gain14','Gain',num2str(Ga14));
        end

        % Value changed function: Gain15
        function Gain15_Callback(app, event)
            % --- Executes on slider movement.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            Ga15=get(hObject,'Value');
            set_param('Ecualizador/Gain15','Gain',num2str(Ga15));
        end

        % Value changed function: Gain1
        function Gain1_Callback(app, event)
            % --- Executes on slider movement.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            Ga1=get(hObject,'Value');
            set_param('Ecualizador/Gain1','Gain',num2str(Ga1));
        end

        % Value changed function: Gain2
        function Gain2_Callback(app, event)
            % --- Executes on slider movement.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            Ga2=get(hObject,'Value');
            set_param('Ecualizador/Gain2','Gain',num2str(Ga2));
        end

        % Value changed function: Gain3
        function Gain3_Callback(app, event)
            % --- Executes on slider movement.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            Ga3=get(hObject,'Value');
            set_param('Ecualizador/Gain3','Gain',num2str(Ga3));
        end

        % Value changed function: Gain4
        function Gain4_Callback(app, event)
            % --- Executes on slider movement.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            Ga4=get(hObject,'Value');
            set_param('Ecualizador/Gain4','Gain',num2str(Ga4));
        end

        % Value changed function: Gain5
        function Gain5_Callback(app, event)
            % --- Executes on slider movement.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            Ga5=get(hObject,'Value');
            set_param('Ecualizador/Gain5','Gain',num2str(Ga5));
        end

        % Value changed function: Gain6
        function Gain6_Callback(app, event)
            % --- Executes on slider movement.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            Ga6=get(hObject,'Value');
            set_param('Ecualizador/Gain6','Gain',num2str(Ga6));
        end

        % Value changed function: Gain7
        function Gain7_Callback(app, event)
            % --- Executes on slider movement.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            Ga7=get(hObject,'Value');
            set_param('Ecualizador/Gain7','Gain',num2str(Ga7));
        end

        % Value changed function: Gain8
        function Gain8_Callback(app, event)
            % --- Executes on slider movement.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            Ga8=get(hObject,'Value');
            set_param('Ecualizador/Gain8','Gain',num2str(Ga8));
        end

        % Value changed function: Gain9
        function Gain9_Callback(app, event)
            % --- Executes on slider movement.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            Ga9=get(hObject,'Value');
            set_param('Ecualizador/Gain9','Gain',num2str(Ga9));
        end

        % Button pushed function: PARA
        function PARA_Callback(app, event)
            % Detener el timer
            if ~isempty(app.TimerReproduccion) && ...
                    isvalid(app.TimerReproduccion)

                if strcmp(app.TimerReproduccion.Running, 'on')
                    stop(app.TimerReproduccion);
                end
            end

            % Detener Simulink
            set_param(gcs,'SimulationCommand','Stop');

            % Reiniciar contador
            app.TiempoActual = 0;
            app.TiempoAcumulado = 0;
            app.TiempoInicio = [];

            % Mostrar 00:00
            app.TiempoActualLabel.Text = '00:00';
        end

        % Button pushed function: PAUSA
        function PAUSA_Callback(app, event)
            % Guardar el tiempo transcurrido antes de pausar
            if ~isempty(app.TimerReproduccion) && ...
                    isvalid(app.TimerReproduccion) && ...
                    strcmp(app.TimerReproduccion.Running, 'on')

                app.TiempoAcumulado = ...
                    app.TiempoAcumulado + toc(app.TiempoInicio);

                app.TiempoActual = app.TiempoAcumulado;

                % Detener el timer
                stop(app.TimerReproduccion);
            end

            % Pausar Simulink
            set_param(gcs,'SimulationCommand','Pause');
        end

        % Button pushed function: PLAY
        function PLAY_Callback(app, event)

            if isempty(app.ArchivoActual)
                uialert(app.figure1, ...
                    'Primero debes cargar una música.', ...
                    'Sin música');
                return;
            end

            % Detener timer anterior
            if ~isempty(app.TimerReproduccion) && ...
                    isvalid(app.TimerReproduccion)

                if strcmp(app.TimerReproduccion.Running, 'on')
                    stop(app.TimerReproduccion);
                end
            end

            % Detener Simulink
            set_param('Ecualizador', ...
                'SimulationCommand', 'Stop');

            % Esperar a que realmente se detenga
            while ~strcmp(get_param('Ecualizador', ...
                    'SimulationStatus'), 'stopped')

                pause(0.05);

            end

            % ==========================================
            % REINICIAR LA CANCIÓN DESDE EL PRINCIPIO
            % ==========================================

            set_param( ...
                'Ecualizador/From Multimedia File', ...
                'readRange', '[1 Inf]');

            % Reiniciar tiempo
            app.TiempoActual = 0;
            app.TiempoAcumulado = 0;

            % Nuevo punto de inicio
            app.TiempoInicio = tic;

            % Mostrar 00:00
            app.TiempoActualLabel.Text = '00:00';

            % ==========================================
            % INICIAR SIMULINK
            % ==========================================

            set_param('Ecualizador', ...
                'SimulationCommand', 'Start');

            % Crear timer si no existe
            if isempty(app.TimerReproduccion) || ...
                    ~isvalid(app.TimerReproduccion)

                app.TimerReproduccion = timer( ...
                    'ExecutionMode', 'fixedRate', ...
                    'Period', 0.2, ...
                    'BusyMode', 'drop', ...
                    'TimerFcn', ...
                    @(src,event) app.actualizarTiempo());

            end

            % Iniciar timer
            if strcmp(app.TimerReproduccion.Running, 'off')
                start(app.TimerReproduccion);
            end

        end

        % Value changed function: VOLUMEN
        function VOLUMEN_Callback(app, event)
            % --- Executes on slider movement.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            volu=get(hObject,'Value');
            set_param('Ecualizador/Gainecua','Gain',num2str(volu));
        end

        % Button pushed function: CARGARMUSICAButton
        function CARGARMUSICAButtonPushed(app, event)
         
                [archivo, ruta] = uigetfile( ...
                    {'*.mp3;*.wav;*.flac;*.m4a;*.ogg', ...
                    'Archivos de audio'}, ...
                    'Seleccionar música');
            
                % Si el usuario cancela
                if isequal(archivo, 0)
                    return;
                end
            
                % =========================================================
                % DETENER TIMER
                % =========================================================
            
                if ~isempty(app.TimerReproduccion) && ...
                        isvalid(app.TimerReproduccion)
            
                    if strcmp(app.TimerReproduccion.Running, 'on')
                        stop(app.TimerReproduccion);
                    end
            
                end
            
                % =========================================================
                % DETENER SIMULINK
                % =========================================================
            
                if bdIsLoaded('Ecualizador')
            
                    estado = get_param('Ecualizador', ...
                        'SimulationStatus');
            
                    if ~strcmp(estado, 'stopped')
            
                        set_param('Ecualizador', ...
                            'SimulationCommand', 'Stop');
            
                        % Esperar hasta que Simulink realmente se detenga
                        while ~strcmp(get_param('Ecualizador', ...
                                'SimulationStatus'), 'stopped')
            
                            pause(0.05);
            
                        end
            
                    end
            
                end
            
                % =========================================================
                % GUARDAR NUEVO ARCHIVO
                % =========================================================
            
                app.ArchivoActual = fullfile(ruta, archivo);
            
                % Mostrar nombre
                app.NombreMusicaLabel.Text = archivo;
            
                % =========================================================
                % ACTUALIZAR INFORMACIÓN DEL AUDIO
                % =========================================================
            
                actualizarDuracion(app);
            
                % =========================================================
                % REINICIAR CONTADOR
                % =========================================================
            
                app.TiempoActual = 0;
                app.TiempoAcumulado = 0;
                app.TiempoInicio = [];
            
                app.TiempoActualLabel.Text = '00:00';
            
                % =========================================================
                % MOSTRAR INFORMACIÓN
                % =========================================================
            
                fprintf('Archivo seleccionado:\n%s\n', ...
                    app.ArchivoActual);
            
                fprintf('Duración: %.2f segundos\n', ...
                    app.DuracionMusica);
            
                fprintf('Frecuencia de muestreo: %d Hz\n', ...
                    app.SampleRateMusica);
            
                % =========================================================
                % CAMBIAR ARCHIVO EN SIMULINK
                % =========================================================
            
                set_param( ...
                    'Ecualizador/From Multimedia File', ...
                    'inputFilename', ...
                    app.ArchivoActual);
            
                % =========================================================
                % REINICIAR POSICIÓN AL INICIO
                % =========================================================
            
                set_param( ...
                    'Ecualizador/From Multimedia File', ...
                    'readRange', '[1 Inf]');
            
                fprintf('Archivo cargado en Simulink correctamente.\n');
        end

        % Value changed function: SECCIONARMODODropDown
        function SECCIONARMODODropDownValueChanged(app, event)
            genero = app.SECCIONARMODODropDown.Value;
            switch genero

                case 'Normal'
                    % Sin alteración
                    ganancias = [1.00 1.00 1.00 1.00 1.00 1.00 1.00 1.00 1.00 1.00 1.00 1.00 1.00 1.00 1.00];

                case 'Rock'
                    % Realce de graves y agudos, ligera atenuación de medios
                    ganancias = [1.35 1.30 1.25 1.15 1.00 0.90 0.85 0.90 0.95 1.10 1.20 1.30 1.35 1.35 1.30];

                case 'Pop'
                    % Graves moderados y realce de medios-altos y agudos
                    ganancias = [0.95 1.00 1.05 1.15 1.20 1.20 1.10 1.05 1.10 1.15 1.20 1.15 1.10 1.05 1.00];

                case 'Jazz'
                    % Graves cálidos y agudos suaves
                    ganancias = [1.20 1.20 1.15 1.10 1.05 0.95 0.90 0.95 1.00 1.10 1.15 1.20 1.15 1.10 1.05];

                case 'Clásica'
                    % Curva suave y equilibrada
                    ganancias = [1.20 1.15 1.10 1.05 1.00 1.00 1.00 1.00 1.00 1.05 1.10 1.15 1.20 1.15 1.10];

                case 'Electrónica'
                    % Graves profundos y agudos brillantes
                    ganancias = [1.50 1.45 1.40 1.25 1.05 0.90 0.95 1.05 1.15 1.25 1.35 1.45 1.40 1.30 1.20];

                case 'Vocal'
                    % Atenuación de graves y realce de la zona vocal
                    ganancias = [0.65 0.75 0.85 0.95 1.10 1.25 1.40 1.45 1.40 1.30 1.15 1.00 0.90 0.85 0.80];

                case 'Bass Boost'
                    % Realce fuerte de las primeras bandas
                    ganancias = [1.50 1.45 1.40 1.30 1.20 1.10 1.00 0.95 0.90 0.90 0.95 1.00 1.00 0.95 0.90];

            end

            % Aplicar ganancias a los sliders y a Simulink
            app.actualizarGains(ganancias);

        end

        % Button pushed function: ADELANTARButton
        function ADELANTARButtonPushed(app, event)

            % Verificar que haya una canción cargada
            if isempty(app.ArchivoActual)
                return;
            end
        
            % -------------------------------------------------
            % CALCULAR NUEVA POSICIÓN
            % -------------------------------------------------
        
            salto = 10;
        
            nuevoTiempo = app.TiempoActual + salto;
        
            % No superar la duración
            if nuevoTiempo >= app.DuracionMusica
                nuevoTiempo = app.DuracionMusica;
            end
        
            % -------------------------------------------------
            % DETENER TIMER
            % -------------------------------------------------
        
            if ~isempty(app.TimerReproduccion) && ...
                    isvalid(app.TimerReproduccion)
        
                if strcmp(app.TimerReproduccion.Running, 'on')
                    stop(app.TimerReproduccion);
                end
        
            end
        
            % -------------------------------------------------
            % DETENER SIMULINK
            % -------------------------------------------------
        
            set_param('Ecualizador', ...
                'SimulationCommand', 'Stop');
        
            % Esperar hasta que realmente esté detenido
            while ~strcmp(get_param('Ecualizador', ...
                    'SimulationStatus'), 'stopped')
        
                pause(0.05);
        
            end
        
            % -------------------------------------------------
            % CONVERTIR SEGUNDOS A MUESTRAS
            % -------------------------------------------------
        
            muestraInicial = round( ...
                nuevoTiempo * app.SampleRateMusica) + 1;
        
            % -------------------------------------------------
            % CAMBIAR READ RANGE
            % -------------------------------------------------
        
            set_param( ...
                'Ecualizador/From Multimedia File', ...
                'readRange', ...
                sprintf('[%d Inf]', muestraInicial));
        
            % -------------------------------------------------
            % ACTUALIZAR TIEMPO
            % -------------------------------------------------
        
            app.TiempoActual = nuevoTiempo;
            app.TiempoAcumulado = nuevoTiempo;
        
            minutos = floor(app.TiempoActual / 60);
            segundos = floor(mod(app.TiempoActual, 60));
        
            app.TiempoActualLabel.Text = sprintf( ...
                '%02d:%02d', ...
                minutos, segundos);
        
            % -------------------------------------------------
            % NUEVO PUNTO DE REFERENCIA
            % -------------------------------------------------
        
            app.TiempoInicio = tic;
        
            % -------------------------------------------------
            % REINICIAR SIMULINK
            % -------------------------------------------------
        
            if nuevoTiempo < app.DuracionMusica
        
                set_param('Ecualizador', ...
                    'SimulationCommand', 'Start');
        
                % Crear timer si no existe
                if isempty(app.TimerReproduccion) || ...
                        ~isvalid(app.TimerReproduccion)
        
                    app.TimerReproduccion = timer( ...
                        'ExecutionMode', 'fixedRate', ...
                        'Period', 0.2, ...
                        'BusyMode', 'drop', ...
                        'TimerFcn', ...
                        @(src,event) app.actualizarTiempo());
        
                end
        
                % Iniciar timer
                if strcmp(app.TimerReproduccion.Running, 'off')
                    start(app.TimerReproduccion);
                end

    end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'REPRODUCIR';
            app.figure1.Tag = 'figure1';
            app.Image = uiimage(app.figure1);
            app.Image.ImageSource = fullfile(pathToMLAPP, '2a0621e5-fcc4-45f5-a982-4b685b798397.png (1).jpg');
            app.PLAY = uibutton(app.figure1, 'push');
            app.PLAY.ButtonPushedFcn = createCallbackFcn(app, @PLAY_Callback, true);
            app.PLAY.Tag = 'PLAY';
            app.PLAY.Text = 'PLAY';
            app.PAUSA = uibutton(app.figure1, 'push');
            app.PAUSA.ButtonPushedFcn = createCallbackFcn(app, @PAUSA_Callback, true);
            app.PAUSA.Tag = 'PAUSA';
            app.PAUSA.Text = 'PAUSA';
            app.CONTINUAR = uibutton(app.figure1, 'push');
            app.CONTINUAR.ButtonPushedFcn = createCallbackFcn(app, @CONTINUAR_Callback, true);
            app.CONTINUAR.Tag = 'CONTINUAR';
            app.CONTINUAR.Text = 'CONTINUAR';
            app.PARA = uibutton(app.figure1, 'push');
            app.PARA.ButtonPushedFcn = createCallbackFcn(app, @PARA_Callback, true);
            app.PARA.Tag = 'PARA';
            app.PARA.Text = 'DETENER';
            app.Gain1 = uislider(app.figure1);
            app.Gain1.Limits = [0 1.5];
            app.Gain1.MajorTicks = [];
            app.Gain1.ValueChangedFcn = createCallbackFcn(app, @Gain1_Callback, true);
            app.Gain1.MinorTicks = [0 0.05 0.1 0.15 0.2 0.25 0.3 0.35 0.4 0.45 0.5 0.55 0.6 0.65 0.7 0.75 0.8 0.85 0.9 0.95 1];
            app.Gain1.Step = 0.01;
            app.Gain1.Tag = 'Gain1';
            app.Gain1.Value = 1;
            app.Gain2 = uislider(app.figure1);
            app.Gain2.Limits = [0 1.5];
            app.Gain2.MajorTicks = [];
            app.Gain2.ValueChangedFcn = createCallbackFcn(app, @Gain2_Callback, true);
            app.Gain2.MinorTicks = [0 0.05 0.1 0.15 0.2 0.25 0.3 0.35 0.4 0.45 0.5 0.55 0.6 0.65 0.7 0.75 0.8 0.85 0.9 0.95 1];
            app.Gain2.Step = 0.01;
            app.Gain2.Tag = 'Gain2';
            app.Gain2.Value = 1;
            app.Gain3 = uislider(app.figure1);
            app.Gain3.Limits = [0 1.5];
            app.Gain3.MajorTicks = [];
            app.Gain3.ValueChangedFcn = createCallbackFcn(app, @Gain3_Callback, true);
            app.Gain3.MinorTicks = [0 0.05 0.1 0.15 0.2 0.25 0.3 0.35 0.4 0.45 0.5 0.55 0.6 0.65 0.7 0.75 0.8 0.85 0.9 0.95 1];
            app.Gain3.Step = 0.01;
            app.Gain3.Tag = 'Gain3';
            app.Gain3.Value = 1;
            app.Gain4 = uislider(app.figure1);
            app.Gain4.Limits = [0 1.5];
            app.Gain4.MajorTicks = [];
            app.Gain4.ValueChangedFcn = createCallbackFcn(app, @Gain4_Callback, true);
            app.Gain4.MinorTicks = [0 0.05 0.1 0.15 0.2 0.25 0.3 0.35 0.4 0.45 0.5 0.55 0.6 0.65 0.7 0.75 0.8 0.85 0.9 0.95 1];
            app.Gain4.Step = 0.01;
            app.Gain4.Tag = 'Gain4';
            app.Gain4.Value = 1;
            app.VOLUMEN = uislider(app.figure1);
            app.VOLUMEN.Limits = [0 1.5];
            app.VOLUMEN.MajorTicks = [];
            app.VOLUMEN.ValueChangedFcn = createCallbackFcn(app, @VOLUMEN_Callback, true);
            app.VOLUMEN.MinorTicks = [0 0.035 0.07 0.105 0.14 0.175 0.21 0.245 0.28 0.315 0.35 0.385 0.42 0.455 0.49 0.525 0.56 0.595 0.63 0.665 0.7 0.735 0.77 0.805 0.84 0.875 0.91 0.945 1];
            app.VOLUMEN.Step = 0.01;
            app.VOLUMEN.Tag = 'VOLUMEN';
            app.VOLUMEN.Value = 1.5;
            app.Gain5 = uislider(app.figure1);
            app.Gain5.Limits = [0 1.5];
            app.Gain5.MajorTicks = [];
            app.Gain5.ValueChangedFcn = createCallbackFcn(app, @Gain5_Callback, true);
            app.Gain5.MinorTicks = [0 0.05 0.1 0.15 0.2 0.25 0.3 0.35 0.4 0.45 0.5 0.55 0.6 0.65 0.7 0.75 0.8 0.85 0.9 0.95 1];
            app.Gain5.Step = 0.01;
            app.Gain5.Tag = 'Gain5';
            app.Gain5.Value = 1;
            app.Gain6 = uislider(app.figure1);
            app.Gain6.Limits = [0 1.5];
            app.Gain6.MajorTicks = [];
            app.Gain6.ValueChangedFcn = createCallbackFcn(app, @Gain6_Callback, true);
            app.Gain6.MinorTicks = [0 0.05 0.1 0.15 0.2 0.25 0.3 0.35 0.4 0.45 0.5 0.55 0.6 0.65 0.7 0.75 0.8 0.85 0.9 0.95 1];
            app.Gain6.Step = 0.01;
            app.Gain6.Tag = 'Gain6';
            app.Gain6.Value = 1;
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'VOLUMEN';
            app.text10 = uilabel(app.figure1);
            app.text10.Tag = 'text10';
            app.text10.Text = 'ECUALIZADOR DE BANDAS DE FRECUENCIA';
            app.Gain7 = uislider(app.figure1);
            app.Gain7.Limits = [0 1.5];
            app.Gain7.MajorTicks = [];
            app.Gain7.ValueChangedFcn = createCallbackFcn(app, @Gain7_Callback, true);
            app.Gain7.MinorTicks = [0 0.05 0.1 0.15 0.2 0.25 0.3 0.35 0.4 0.45 0.5 0.55 0.6 0.65 0.7 0.75 0.8 0.85 0.9 0.95 1];
            app.Gain7.Step = 0.01;
            app.Gain7.Tag = 'Gain7';
            app.Gain7.Value = 1;
            app.Gain8 = uislider(app.figure1);
            app.Gain8.Limits = [0 1.5];
            app.Gain8.MajorTicks = [];
            app.Gain8.ValueChangedFcn = createCallbackFcn(app, @Gain8_Callback, true);
            app.Gain8.MinorTicks = [0 0.05 0.1 0.15 0.2 0.25 0.3 0.35 0.4 0.45 0.5 0.55 0.6 0.65 0.7 0.75 0.8 0.85 0.9 0.95 1];
            app.Gain8.Step = 0.01;
            app.Gain8.Tag = 'Gain8';
            app.Gain8.Value = 1;
            app.Gain9 = uislider(app.figure1);
            app.Gain9.Limits = [0 2];
            app.Gain9.MajorTicks = [];
            app.Gain9.ValueChangedFcn = createCallbackFcn(app, @Gain9_Callback, true);
            app.Gain9.MinorTicks = [0 0.05 0.1 0.15 0.2 0.25 0.3 0.35 0.4 0.45 0.5 0.55 0.6 0.65 0.7 0.75 0.8 0.85 0.9 0.95 1];
            app.Gain9.Step = 0.01;
            app.Gain9.Tag = 'Gain9';
            app.Gain9.Value = 1.5;
            app.Gain12 = uislider(app.figure1);
            app.Gain12.Limits = [0 2];
            app.Gain12.MajorTicks = [];
            app.Gain12.ValueChangedFcn = createCallbackFcn(app, @Gain12_Callback, true);
            app.Gain12.MinorTicks = [0 0.05 0.1 0.15 0.2 0.25 0.3 0.35 0.4 0.45 0.5 0.55 0.6 0.65 0.7 0.75 0.8 0.85 0.9 0.95 1];
            app.Gain12.Step = 0.01;
            app.Gain12.Tag = 'Gain12';
            app.Gain12.Value = 1.5;
            app.Gain10 = uislider(app.figure1);
            app.Gain10.Limits = [0 2];
            app.Gain10.MajorTicks = [];
            app.Gain10.ValueChangedFcn = createCallbackFcn(app, @Gain10_Callback, true);
            app.Gain10.MinorTicks = [0 0.05 0.1 0.15 0.2 0.25 0.3 0.35 0.4 0.45 0.5 0.55 0.6 0.65 0.7 0.75 0.8 0.85 0.9 0.95 1];
            app.Gain10.Step = 0.01;
            app.Gain10.Tag = 'Gain10';
            app.Gain10.Value = 1.5;
            app.Gain11 = uislider(app.figure1);
            app.Gain11.Limits = [0 2];
            app.Gain11.MajorTicks = [];
            app.Gain11.ValueChangedFcn = createCallbackFcn(app, @Gain11_Callback, true);
            app.Gain11.MinorTicks = [0 0.05 0.1 0.15 0.2 0.25 0.3 0.35 0.4 0.45 0.5 0.55 0.6 0.65 0.7 0.75 0.8 0.85 0.9 0.95 1];
            app.Gain11.Step = 0.01;
            app.Gain11.Tag = 'Gain11';
            app.Gain11.Value = 1.5;
            app.text17 = uilabel(app.figure1);
            app.text17.Tag = 'text17';
            app.text17.Text = '25Hz';
            app.text18 = uilabel(app.figure1);
            app.text18.Tag = 'text18';
            app.text18.Text = '40Hz';
            app.text19 = uilabel(app.figure1);
            app.text19.Tag = 'text19';
            app.text19.Text = '63Hz';
            app.text20 = uilabel(app.figure1);
            app.text20.Tag = 'text20';
            app.text20.Text = '100Hz';
            app.text21 = uilabel(app.figure1);
            app.text21.Tag = 'text21';
            app.text21.Text = '160Hz';
            app.text22 = uilabel(app.figure1);
            app.text22.Tag = 'text22';
            app.text22.Text = '250Hz';
            app.text23 = uilabel(app.figure1);
            app.text23.Tag = 'text23';
            app.text23.Text = '400Hz';
            app.text24 = uilabel(app.figure1);
            app.text24.Tag = 'text24';
            app.text24.Text = '630Hz';
            app.text25 = uilabel(app.figure1);
            app.text25.Tag = 'text25';
            app.text25.Text = '1kHz';
            app.text26 = uilabel(app.figure1);
            app.text26.Tag = 'text26';
            app.text26.Text = '2.5kHz';
            app.text27 = uilabel(app.figure1);
            app.text27.Tag = 'text27';
            app.text27.Text = '1.6kHz';
            app.text28 = uilabel(app.figure1);
            app.text28.Tag = 'text28';
            app.text28.Text = '4kHz';
            app.Gain13 = uislider(app.figure1);
            app.Gain13.Limits = [0 1.5];
            app.Gain13.MajorTicks = [];
            app.Gain13.ValueChangedFcn = createCallbackFcn(app, @Gain13_Callback, true);
            app.Gain13.MinorTicks = [0 0.05 0.1 0.15 0.2 0.25 0.3 0.35 0.4 0.45 0.5 0.55 0.6 0.65 0.7 0.75 0.8 0.85 0.9 0.95 1];
            app.Gain13.Step = 0.01;
            app.Gain13.Tag = 'Gain13';
            app.Gain13.Value = 1;
            app.Gain14 = uislider(app.figure1);
            app.Gain14.Limits = [0 1.5];
            app.Gain14.MajorTicks = [];
            app.Gain14.ValueChangedFcn = createCallbackFcn(app, @Gain14_Callback, true);
            app.Gain14.MinorTicks = [0 0.05 0.1 0.15 0.2 0.25 0.3 0.35 0.4 0.45 0.5 0.55 0.6 0.65 0.7 0.75 0.8 0.85 0.9 0.95 1];
            app.Gain14.Step = 0.01;
            app.Gain14.Tag = 'Gain14';
            app.Gain14.Value = 1;
            app.Gain15 = uislider(app.figure1);
            app.Gain15.Limits = [0 1.5];
            app.Gain15.MajorTicks = [];
            app.Gain15.ValueChangedFcn = createCallbackFcn(app, @Gain15_Callback, true);
            app.Gain15.MinorTicks = [0 0.05 0.1 0.15 0.2 0.25 0.3 0.35 0.4 0.45 0.5 0.55 0.6 0.65 0.7 0.75 0.8 0.85 0.9 0.95 1];
            app.Gain15.Step = 0.01;
            app.Gain15.Tag = 'Gain15';
            app.Gain15.Value = 1;
            app.text29 = uilabel(app.figure1);
            app.text29.Tag = 'text29';
            app.text29.Text = '6.3kHz';
            app.text30 = uilabel(app.figure1);
            app.text30.Tag = 'text30';
            app.text30.Text = '10kHz';
            app.text31 = uilabel(app.figure1);
            app.text31.Tag = 'text31';
            app.text31.Text = '16kHz';
            app.CARGARMUSICAButton = uibutton(app.figure1, 'push');
            app.CARGARMUSICAButton.ButtonPushedFcn = createCallbackFcn(app, @CARGARMUSICAButtonPushed, true);
            app.CARGARMUSICAButton.Tag = 'CargarMusica';
            app.CARGARMUSICAButton.Text = 'CARGAR MUSICA';
            app.SECCIONARMODODropDownLabel = uilabel(app.figure1);
            app.SECCIONARMODODropDownLabel.Text = 'SECCIONAR MODO';
            app.SECCIONARMODODropDown = uidropdown(app.figure1);
            app.SECCIONARMODODropDown.Items = {'Normal', 'Rock', 'Pop', 'Jazz', 'Clásica', 'Electrónica', 'Vocal', 'Bass Boost'};
            app.SECCIONARMODODropDown.ValueChangedFcn = createCallbackFcn(app, @SECCIONARMODODropDownValueChanged, true);
            app.SECCIONARMODODropDown.Tag = 'GeneroDropDown';
            app.SECCIONARMODODropDown.Value = 'Normal';
            app.NombreMusicaLabel = uilabel(app.figure1);
            app.NombreMusicaLabel.Text = 'NombreMusicaLabel';
            app.DuracionLabel = uilabel(app.figure1);
            app.DuracionLabel.Tag = 'DuracionLabel';
            app.DuracionLabel.Text = 'Duración: 00:00';
            app.TiempoActualLabel = uilabel(app.figure1);
            app.TiempoActualLabel.Tag = 'TiempoActualLabel';
            app.TiempoActualLabel.Text = '00:00';
            app.ADELANTARButton = uibutton(app.figure1, 'push');
            app.ADELANTARButton.ButtonPushedFcn = createCallbackFcn(app, @ADELANTARButtonPushed, true);
            app.ADELANTARButton.Tag = 'ADELANTAR';
            app.ADELANTARButton.Text = 'ADELANTAR';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = REPRODUCIR_App

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @startupFcn2)
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end